classdef Functia_inpolygon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GenereazaButton           matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        xvEditField               matlab.ui.control.EditField
        xvEditFieldLabel          matlab.ui.control.Label
        yvEditField               matlab.ui.control.EditField
        yvEditFieldLabel          matlab.ui.control.Label
        yqEditField               matlab.ui.control.EditField
        yqEditFieldLabel          matlab.ui.control.Label
        xqEditField               matlab.ui.control.EditField
        xqEditFieldLabel          matlab.ui.control.Label
        CuloareONDropDown         matlab.ui.control.DropDown
        CuloareONDropDownLabel    matlab.ui.control.Label
        MarcatoriONDropDown       matlab.ui.control.DropDown
        MarcatoriONDropDownLabel  matlab.ui.control.Label
        StilLinieONDropDown       matlab.ui.control.DropDown
        StilLinieONDropDownLabel  matlab.ui.control.Label
        CuloareINDropDown         matlab.ui.control.DropDown
        CuloareINDropDownLabel    matlab.ui.control.Label
        MarcatoriINDropDown       matlab.ui.control.DropDown
        MarcatoriINDropDownLabel  matlab.ui.control.Label
        StillinieINDropDown       matlab.ui.control.DropDown
        StillinieINDropDownLabel  matlab.ui.control.Label
        FunctiainpolygonLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        xq = [];
        yq = [];
        xv = [];
        yv = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenereazaButton
        function GenereazaButtonPushed(app, event)
    app.xq = str2num(app.xqEditField.Value);
    app.yq = str2num(app.yqEditField.Value);
    app.xv = str2num(app.xvEditField.Value);
    app.yv = str2num(app.yvEditField.Value);

    figure (1);
    [in] = inpolygon(app.xq, app.yq, app.xv, app.yv);

    plot(app.xq(in), app.yq(in), ...
        'LineStyle',app.StillinieINDropDown.Value, ...
        'Marker',app.MarcatoriINDropDown.Value, ...
        'Color',app.CuloareINDropDown.Value);
    hold on;
    
    plot([app.xv app.xv(1)], [app.yv app.yv(1)], 'k-'); 
    title("IN");
    hold off;

    figure (2)
    [in,on] = inpolygon(app.xq, app.yq, app.xv, app.yv);

    plot(app.xq(in), app.yq(in), ...
        'LineStyle',app.StillinieINDropDown.Value, ...
        'Marker',app.MarcatoriINDropDown.Value, ...
        'Color',app.CuloareINDropDown.Value);
    hold on;

    plot(app.xq(on), app.yq(on), ...
        'LineStyle',app.StilLinieONDropDown.Value, ...
        'Marker',app.MarcatoriONDropDown.Value, ...
        'Color',app.CuloareONDropDown.Value);
    
    plot([app.xv app.xv(1)], [app.yv app.yv(1)], 'k-'); 
    title("IN and ON");
    hold off;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FunctiainpolygonLabel = uilabel(app.UIFigure);
            app.FunctiainpolygonLabel.Text = 'Functia "inpolygon"';
            app.StillinieINDropDownLabel = uilabel(app.UIFigure);
            app.StillinieINDropDownLabel.Text = 'Stil linie (IN)';
            app.StillinieINDropDown = uidropdown(app.UIFigure);
            app.StillinieINDropDown.Items = {'none', '-', '--', ':', '-.'};
            app.StillinieINDropDown.Value = 'none';
            app.MarcatoriINDropDownLabel = uilabel(app.UIFigure);
            app.MarcatoriINDropDownLabel.Text = 'Marcatori (IN)';
            app.MarcatoriINDropDown = uidropdown(app.UIFigure);
            app.MarcatoriINDropDown.Items = {'+', '*', 'o', 'x', 's', 'd', '^', 'p', 'h'};
            app.MarcatoriINDropDown.Value = '+';
            app.CuloareINDropDownLabel = uilabel(app.UIFigure);
            app.CuloareINDropDownLabel.Text = 'Culoare (IN)';
            app.CuloareINDropDown = uidropdown(app.UIFigure);
            app.CuloareINDropDown.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'white', 'black'};
            app.CuloareINDropDown.Value = 'red';
            app.StilLinieONDropDownLabel = uilabel(app.UIFigure);
            app.StilLinieONDropDownLabel.Text = 'Stil Linie (ON)';
            app.StilLinieONDropDown = uidropdown(app.UIFigure);
            app.StilLinieONDropDown.Items = {'none', '-', '--', ':', '-.'};
            app.StilLinieONDropDown.Value = 'none';
            app.MarcatoriONDropDownLabel = uilabel(app.UIFigure);
            app.MarcatoriONDropDownLabel.Text = 'Marcatori (ON)';
            app.MarcatoriONDropDown = uidropdown(app.UIFigure);
            app.MarcatoriONDropDown.Items = {'+', '*', 'o', 'x', 's', 'd', '^', 'p', 'h'};
            app.MarcatoriONDropDown.Value = '+';
            app.CuloareONDropDownLabel = uilabel(app.UIFigure);
            app.CuloareONDropDownLabel.Text = 'Culoare (ON)';
            app.CuloareONDropDown = uidropdown(app.UIFigure);
            app.CuloareONDropDown.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'white', 'black'};
            app.CuloareONDropDown.Value = 'red';
            app.xqEditFieldLabel = uilabel(app.UIFigure);
            app.xqEditFieldLabel.Text = 'xq';
            app.xqEditField = uieditfield(app.UIFigure, 'text');
            app.yqEditFieldLabel = uilabel(app.UIFigure);
            app.yqEditFieldLabel.Text = 'yq';
            app.yqEditField = uieditfield(app.UIFigure, 'text');
            app.yvEditFieldLabel = uilabel(app.UIFigure);
            app.yvEditFieldLabel.Text = 'yv';
            app.yvEditField = uieditfield(app.UIFigure, 'text');
            app.xvEditFieldLabel = uilabel(app.UIFigure);
            app.xvEditFieldLabel.Text = 'xv';
            app.xvEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-------------------------------------------';
            app.GenereazaButton = uibutton(app.UIFigure, 'push');
            app.GenereazaButton.ButtonPushedFcn = createCallbackFcn(app, @GenereazaButtonPushed, true);
            app.GenereazaButton.Text = 'Genereaza';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Functia_inpolygon

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end